classdef HDsEMG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RecordingSettingsPanel          matlab.ui.container.Panel
        RefreshrateDropDown             matlab.ui.control.DropDown
        RefreshrateDropDownLabel        matlab.ui.control.Label
        LPfilterDropDown                matlab.ui.control.DropDown
        LPfilterDropDownLabel           matlab.ui.control.Label
        HPfilterDropDown                matlab.ui.control.DropDown
        HPfilterDropDownLabel           matlab.ui.control.Label
        StartVisualizationChecknoiseButton  matlab.ui.control.Button
        SelectfolderButton              matlab.ui.control.Button
        EditField_saving                matlab.ui.control.EditField
        SavingsettingsLabel             matlab.ui.control.Label
        StartVisualizationCheckEMGButton  matlab.ui.control.Button
        FrequencyDropDown               matlab.ui.control.DropDown
        FrequencyDropDownLabel          matlab.ui.control.Label
        MusclenamesLabel                matlab.ui.control.Label
        ChannelsLabel                   matlab.ui.control.Label
        EditField_4                     matlab.ui.control.EditField
        EditField_3                     matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        EditField_1                     matlab.ui.control.EditField
        Grid4CheckBox                   matlab.ui.control.CheckBox
        Grid3CheckBox                   matlab.ui.control.CheckBox
        Grid2CheckBox                   matlab.ui.control.CheckBox
        Grid1CheckBox                   matlab.ui.control.CheckBox
        VisualizationPanel              matlab.ui.container.Panel
        InitializeQuattrocentoButton    matlab.ui.control.Button
        DisconnectQuattrocentoButton    matlab.ui.control.Button
        GriddisplayedDropDownLabel      matlab.ui.control.Label
        GriddisplayedDropDown           matlab.ui.control.DropDown
        UIAxes3                         matlab.ui.control.UIAxes
        TrainingSettingsPanel           matlab.ui.container.Panel
        COVthresholdEditField           matlab.ui.control.NumericEditField
        COVthresholdEditFieldLabel      matlab.ui.control.Label
        SILthresholdEditField           matlab.ui.control.NumericEditField
        SILthresholdEditFieldLabel      matlab.ui.control.Label
        EditField_MVC                   matlab.ui.control.EditField
        StartButton                     matlab.ui.control.Button
        NbIterationsEditField           matlab.ui.control.NumericEditField
        NbIterationsEditFieldLabel      matlab.ui.control.Label
        TrainingparametersLabel         matlab.ui.control.Label
        DurationtrainingEditField       matlab.ui.control.NumericEditField
        DurationtrainingEditFieldLabel  matlab.ui.control.Label
        MVCTargetEditField              matlab.ui.control.NumericEditField
        MVCTargetEditFieldLabel         matlab.ui.control.Label
        Gauge                           matlab.ui.control.SemicircularGauge
        StartMVCButton                  matlab.ui.control.Button
        EditField_offset                matlab.ui.control.NumericEditField
        OffsetCheckBox                  matlab.ui.control.CheckBox
        RestEditField                   matlab.ui.control.NumericEditField
        RestEditFieldLabel              matlab.ui.control.Label
        DurationEditField               matlab.ui.control.NumericEditField
        DurationEditFieldLabel          matlab.ui.control.Label
        MVCparametersLabel              matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        BiofeedbackSettingsPanel        matlab.ui.container.Panel
        TypeofbiofeedbacksLabel         matlab.ui.control.Label
        ForceparametersLabel            matlab.ui.control.Label
        StartDRofalltheMUsButton        matlab.ui.control.Button
        StartQuadrantwithMUsButton      matlab.ui.control.Button
        StartQuadrantwithCSTsButton     matlab.ui.control.Button
        NbiterationsEditField           matlab.ui.control.NumericEditField
        NbiterationsEditFieldLabel      matlab.ui.control.Label
        DurationrestEditField           matlab.ui.control.NumericEditField
        DurationrestEditFieldLabel      matlab.ui.control.Label
        DurationplateauEditField        matlab.ui.control.NumericEditField
        DurationplateauEditFieldLabel   matlab.ui.control.Label
        DurationrampEditField           matlab.ui.control.NumericEditField
        DurationrampEditFieldLabel      matlab.ui.control.Label
        MVCtargetEditField              matlab.ui.control.NumericEditField
        MVCtargetEditFieldLabel         matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        VisualizationBiofeedback        matlab.ui.container.Panel
        InitializeQuattrocentoButton_2  matlab.ui.control.Button
        YaxisdisplayedDropDownLabel     matlab.ui.control.Label
        YaxisdisplayedDropDown          matlab.ui.control.DropDown
        DisconnectQuattrocentoButton_2  matlab.ui.control.Button
        XaxisdisplayedDropDownLabel     matlab.ui.control.Label
        XaxisdisplayedDropDown          matlab.ui.control.DropDown
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        RecordingTab                    matlab.ui.container.Tab
        TrainingTab                     matlab.ui.container.Tab
        BiofeedbackTab                  matlab.ui.container.Tab
    end

    
    properties (Access = private)
        
        Pathname            % Folder where files will be saved      
        ComParameters       % Structure to save the parameters to interface with the quattrocento
        RecordParameters    % Structure to save the recording parameters (masks, muscles, MVC, offset)
        DecompParameters    % Structure to save the parameters of the decomposition (MU filters, extension matrix, centroids)
        OnlineParameters    % Structure to save the parameters of the online feedback
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: BiofeedbackTab
        function BiofeedbackTabButtonDown(app, event)
            app.BiofeedbackSettingsPanel.Visible = 'on';
            app.TrainingSettingsPanel.Visible = 'off';
            app.RecordingSettingsPanel.Visible = 'off';
            app.VisualizationPanel.Visible = 'off';
            app.VisualizationBiofeedback.Visible = 'on';
        end

        % Button down function: RecordingTab
        function RecordingTabButtonDown(app, event)
            app.BiofeedbackSettingsPanel.Visible = 'off';
            app.TrainingSettingsPanel.Visible = 'off';
            app.RecordingSettingsPanel.Visible = 'on';
            app.VisualizationPanel.Visible = 'on';
            app.VisualizationBiofeedback.Visible = 'off';
        end

        % Button down function: TrainingTab
        function TrainingTabButtonDown(app, event)
            app.BiofeedbackSettingsPanel.Visible = 'off';
            app.TrainingSettingsPanel.Visible = 'on';
            app.RecordingSettingsPanel.Visible = 'off';
            app.VisualizationPanel.Visible = 'on';
            app.VisualizationBiofeedback.Visible = 'off';
        end

        % Button pushed function: SelectfolderButton
        function SelectfolderButtonPushed(app, event)
            app.UIFigure.Visible = 'off'; 
            app.Pathname = uigetdir;
            app.UIFigure.Visible = 'on';
            app.EditField_saving.Value = app.Pathname;
        end

        % Value changed function: Grid1CheckBox
        function Grid1CheckBoxValueChanged(app, event)
            if app.Grid1CheckBox.Value == 1
                app.EditField_1.Editable = 'on';
                app.Grid2CheckBox.Enable = 'on';
                app.RecordParameters.ngrid = 1;
                app.StartVisualizationChecknoiseButton.Enable = 'on';
                app.StartVisualizationCheckEMGButton.Enable = 'on';
                app.InitializeQuattrocentoButton.Enable = 'on';
                app.ComParameters.nchannel = 120;
                app.ComParameters.gridchannels = 33:1:96;
            else
                app.EditField_1.Editable = 'off';
                app.Grid2CheckBox.Enable = 'off';
                app.EditField_1.Value = '';
                app.EditField_2.Value = '';
                app.RecordParameters.ngrid = 0;
                app.GriddisplayedDropDown.Items = {'No Muscles'};
                app.GriddisplayedDropDown.Value = 'No Muscles';
                app.StartVisualizationChecknoiseButton.Enable = 'off';
                app.StartVisualizationCheckEMGButton.Enable = 'off';
                app.InitializeQuattrocentoButton.Enable = 'off';
            end
        end

        % Value changed function: Grid2CheckBox
        function Grid2CheckBoxValueChanged(app, event)
            if app.Grid2CheckBox.Value == 1
                app.EditField_2.Editable = 'on';
                app.Grid3CheckBox.Enable = 'on';
                app.RecordParameters.ngrid = 2;
                app.ComParameters.nchannel = 216;
                app.ComParameters.gridchannels = 65:1:192;
            else
                app.EditField_2.Editable = 'off';
                app.Grid2CheckBox.Enable = 'off';
                app.Grid3CheckBox.Enable = 'off';
                app.EditField_2.Value = '';
                app.EditField_3.Value = '';
                app.RecordParameters.ngrid = 1;
                app.ComParameters.nchannel = 120;
                app.ComParameters.gridchannels = 33:1:96;
            end
        end

        % Value changed function: Grid3CheckBox
        function Grid3CheckBoxValueChanged(app, event)
            if app.Grid3CheckBox.Value == 1
                app.EditField_3.Editable = 'on';
                app.Grid4CheckBox.Enable = 'on';
                app.RecordParameters.ngrid = 3;
                app.ComParameters.nchannel = 312;
                app.ComParameters.gridchannels = 97:1:288;
            else
                app.EditField_3.Editable = 'off';
                app.Grid3CheckBox.Enable = 'off';
                app.Grid4CheckBox.Enable = 'off';
                app.EditField_4.Value = '';
                app.EditField_3.Value = '';
                app.RecordParameters.ngrid = 2;
                app.ComParameters.nchannel = 216;
                app.ComParameters.gridchannels = 65:1:192;
            end            
        end

        % Value changed function: Grid4CheckBox
        function Grid4CheckBoxValueChanged(app, event)
            if app.Grid4CheckBox.Value == 1
                app.EditField_4.Editable = 'on';
                app.RecordParameters.ngrid = 4;
                app.ComParameters.nchannel = 408;
                app.ComParameters.gridchannels = 129:1:384;
            else
                app.EditField_4.Editable = 'off';
                app.Grid4CheckBox.Enable = 'off';
                app.EditField_4.Value = '';
                app.RecordParameters.ngrid = 3;
                app.ComParameters.nchannel = 312;
                app.ComParameters.gridchannels = 97:1:288;
            end
        end

        % Value changed function: EditField_1
        function EditField_1ValueChanged(app, event)
            app.GriddisplayedDropDown.Items = {'1'};
            app.GriddisplayedDropDown.Value = '1';
            app.RecordParameters.Muscles = {app.EditField_1.Value};
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.GriddisplayedDropDown.Items = {'1', '2'};
            app.RecordParameters.Muscles = {app.EditField_1.Value, app.EditField_2.Value};
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            app.GriddisplayedDropDown.Items = {'1', '2', '3'};
            app.RecordParameters.Muscles = {app.EditField_1.Value, app.EditField_2.Value, app.EditField_3.Value};
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            app.GriddisplayedDropDown.Items = {'1', '2', '3', '4'};
            app.RecordParameters.Muscles = {app.EditField_1.Value, app.EditField_2.Value, app.EditField_3.Value, app.EditField_4.Value};
        end

        % Button pushed function: InitializeQuattrocentoButton
        function InitializeQuattrocentoButtonPushed(app, event)
            app.ComParameters.fsamp = str2double(app.FrequencyDropDown.Value);
            app.ComParameters.nsamp = floor(app.ComParameters.fsamp/str2double(app.RefreshrateDropDown.Value));
            app.ComParameters.highpass = str2double(app.HPfilterDropDown.Value);
            app.ComParameters.lowpass = str2double(app.LPfilterDropDown.Value);
            app.ComParameters.ConfString = bitseqquattro(app.ComParameters.fsamp, app.ComParameters.nchannel, app.ComParameters.highpass, app.ComParameters.lowpass);
            
            app.ComParameters.tcpSocket = tcpclient('169.254.1.10', 23456);
            app.ComParameters.tcpSocket.InputBufferSize = 2 * app.ComParameters.nchannel * app.ComParameters.fsamp;
            app.GriddisplayedDropDown.Enable = 'on';
            app.InitializeQuattrocentoButton.BackgroundColor = 'Green';
            app.DisconnectQuattrocentoButton.BackgroundColor = [0.15 0.15 0.15];
            app.DisconnectQuattrocentoButton.Enable = 'on';
            app.StartVisualizationCheckEMGButton.Enable = 'on';
            app.StartVisualizationChecknoiseButton.Enable = 'on';
            app.StartButton.Enable = 'on';
            app.StartMVCButton.Enable = 'on';
        end

        % Button pushed function: DisconnectQuattrocentoButton
        function DisconnectQuattrocentoButtonPushed(app, event)
            app.ComParameters.ConfString(1) = bin2dec('10000000'); % Update the first byte that start the data transer
            app.ComParameters.ConfString(40) = CRC8(app.ComParameters.ConfString, 39);            
            write(app.ComParameters.tcpSocket, app.ComParameters.ConfString, 'uint8');
            close(app.ComParameters.tcpSocket); % Close the communication
            flush(app.ComParameters.tcpSocket)
            app.InitializeQuattrocentoButton.BackgroundColor = [0.15 0.15 0.15];
        end

        % Button pushed function: StartVisualizationChecknoiseButton
        function StartVisualizationChecknoiseButtonPushed(app, event)
            
            app.ComParameters.nchannel
            % record 15 seconds of data to assess the baseline noise
            nwin = floor((15*str2double(app.FrequencyDropDown.Value))/app.ComParameters.nsamp);
            EMG = zeros(64*app.RecordParameters.ngrid,nwin*app.ComParameters.nsamp);
            
            channels = 1:1:64;
            offset = ones(64,app.ComParameters.nsamp);
            offset = offset .* channels';

            time = linspace(0,5,5*str2double(app.FrequencyDropDown.Value));
            buffer = zeros(str2double(app.FrequencyDropDown.Value)*5,64);
            i = 1;
            nwinbuff = 1;
            nwinbuffmax = floor(str2double(app.FrequencyDropDown.Value)*5/app.ComParameters.nsamp);

            app.ComParameters.ConfString(1) = app.ComParameters.ConfString(1) + 1; % Update the first byte that start the data transer
            app.ComParameters.ConfString(40) = CRC8(app.ComParameters.ConfString, 39);

            app.UIAxes3.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes3.YColor = [0.9412 0.9412 0.9412];

            % Get the data
            open(app.ComParameters.tcpSocket);
            set(app.ComParameters.tcpSocket, 'ByteOrder', 'little-endian');
            write(app.ComParameters.tcpSocket, app.ComParameters.ConfString, 'uint8'); % Send the configuration to quattrocento
            app.DisconnectQuattrocentoButton.Enable = 'on';
            while i <= nwin
                data = read(app.ComParameters.tcpSocket, [app.ComParameters.nchannel, app.ComParameters.nsamp], 'int16');
                EMG(:,(i-1)*app.ComParameters.nsamp+1:i*app.ComParameters.nsamp) = data(app.ComParameters.gridchannels, :);

                buffer((nwinbuff-1)*app.ComParameters.nsamp+1:nwinbuff*app.ComParameters.nsamp,:) = (EMG((str2double(app.GriddisplayedDropDown.Value)-1)*64+1:str2double(app.GriddisplayedDropDown.Value)*64,(i-1)*app.ComParameters.nsamp+1:i*app.ComParameters.nsamp) * 0.0005 + offset)';
                                
                plot(app.UIAxes3, time, buffer, 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                app.UIAxes3.XLim = [0 5];
                app.UIAxes3.YLim = [0 66];
                line(app.UIAxes3, [time(nwinbuff*app.ComParameters.nsamp) time(nwinbuff*app.ComParameters.nsamp)], [0 64], 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                drawnow limitrate
                i = i + 1;               
                if nwinbuff < nwinbuffmax
                    nwinbuff = nwinbuff+1;
                else
                    nwinbuff = 1;
                end
            end
            app.ComParameters.ConfString(1) = app.ComParameters.ConfString(1) - 1; % Update the first byte that start the data transer
            app.ComParameters.ConfString(40) = CRC8(app.ComParameters.ConfString, 39);            
            write(app.ComParameters.tcpSocket, app.ComParameters.ConfString, 'uint8');
            close(app.ComParameters.tcpSocket); % Close the communication
            flush(app.ComParameters.tcpSocket)

            % Check EMG noise
            Noisecheck = zeros(64,1);
            for j = 1:64
                Noisecheck(j) = rms(EMG((str2double(app.GriddisplayedDropDown.Value)-1)*64+j,str2double(app.FrequencyDropDown.Value):end-str2double(app.FrequencyDropDown.Value))/1000);
            end
            bar(app.UIAxes3,Noisecheck)
            app.UIAxes3.XLim = [0 65];
            app.UIAxes3.YLim = [0 max(Noisecheck)];
            xlabel(app.UIAxes3,'Channels');
            ylabel(app.UIAxes3,'RMS amplitude');
            drawnow
            
            app.StartVisualizationChecknoiseButton.BackgroundColor = 'Green';
            app.InitializeQuattrocentoButton.BackgroundColor = [0.15 0.15 0.15];
            app.StartVisualizationCheckEMGButton.Enable = 'off';
            app.StartVisualizationChecknoiseButton.Enable = 'off';
            app.StartButton.Enable = 'off';
            app.StartMVCButton.Enable = 'off';
        end

        % Button pushed function: StartVisualizationCheckEMGButton
        function StartVisualizationCheckEMGButtonPushed(app, event)
            
            % record 30 seconds of data to assess the quality of the EMG
            % signals and remove noisy channels
            nwin = floor((30*str2double(app.FrequencyDropDown.Value))/app.ComParameters.nsamp);
            EMG = zeros(64*app.RecordParameters.ngrid,nwin*app.ComParameters.nsamp);
            
            channels = 1:1:64;
            offset = ones(64,app.ComParameters.nsamp);
            offset = offset .* channels';

            time = linspace(0,5,5*str2double(app.FrequencyDropDown.Value));
            buffer = zeros(str2double(app.FrequencyDropDown.Value)*5,64);
            i = 1;
            nwinbuff = 1;
            nwinbuffmax = floor(str2double(app.FrequencyDropDown.Value)*5/app.ComParameters.nsamp);            

            app.ComParameters.ConfString(1) = app.ComParameters.ConfString(1) + 1; % Update the first byte that start the data transer
            app.ComParameters.ConfString(40) = CRC8(app.ComParameters.ConfString, 39);

            app.UIAxes3.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes3.YColor = [0.9412 0.9412 0.9412];

            % Get the data
            open(app.ComParameters.tcpSocket);
            set(app.ComParameters.tcpSocket, 'ByteOrder', 'little-endian');
            write(app.ComParameters.tcpSocket, app.ComParameters.ConfString, 'uint8'); % Send the configuration to quattrocento
            app.DisconnectQuattrocentoButton.Enable = 'on';
            while i <= nwin
                data = read(app.ComParameters.tcpSocket, [app.ComParameters.nchannel, app.ComParameters.nsamp], 'int16');
                EMG(:,(i-1)*app.ComParameters.nsamp+1:i*app.ComParameters.nsamp) = data(app.ComParameters.gridchannels, :);

                buffer((nwinbuff-1)*app.ComParameters.nsamp+1:nwinbuff*app.ComParameters.nsamp,:) = (EMG((str2double(app.GriddisplayedDropDown.Value)-1)*64+1:str2double(app.GriddisplayedDropDown.Value)*64,(i-1)*app.ComParameters.nsamp+1:i*app.ComParameters.nsamp) * 0.0005 + offset)';
                                 
                plot(app.UIAxes3, time, buffer, 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                app.UIAxes3.XLim = [0 5];
                app.UIAxes3.YLim = [0 66];
                line(app.UIAxes3, [time(nwinbuff*app.ComParameters.nsamp) time(nwinbuff*app.ComParameters.nsamp)], [0 64], 'LineWidth', 1, 'Color', [0.9412 0.9412 0.9412]);
                drawnow limitrate
                i = i + 1;               
                if nwinbuff < nwinbuffmax
                    nwinbuff = nwinbuff+1;
                else
                    nwinbuff = 1;
                end
            end            
            app.ComParameters.ConfString(1) = app.ComParameters.ConfString(1) - 1; % Update the first byte that start the data transer
            app.ComParameters.ConfString(40) = CRC8(app.ComParameters.ConfString, 39);            
            write(app.ComParameters.tcpSocket, app.ComParameters.ConfString, 'uint8');
            close(app.ComParameters.tcpSocket); % Close the communication
            flush(app.ComParameters.tcpSocket)

            % Check EMG
            for i = 1:app.RecordParameters.ngrid
                EMGcheck = zeros(13,size(EMG,2)*5);
                for j = 1:5
                    if j<5
                        EMGcheck(:,(j-1)*size(EMG,2)+1:j*size(EMG,2)) = EMG((i-1)*64+j*13-12:(i-1)*64+j*13,:);
                    else
                        EMGcheck(1:12,(j-1)*size(EMG,2)+1:j*size(EMG,2)) = EMG((i-1)*64+j*13-12:(i-1)*64+j*13-1,:);
                    end
                end
                channels2 = 1:1:13;
                offset2 = ones(13,size(EMGcheck,2));
                offset2 = offset2 .* channels2';
                EMGcheck = EMGcheck * 0.0005 + offset2;
                plot(app.UIAxes3,EMGcheck')
                line(app.UIAxes3,[size(EMG,2) size(EMG,2)], [0 14], 'LineWidth', 2, 'Color',[0.9412 0.9412 0.9412])
                line(app.UIAxes3,[2*size(EMG,2) 2*size(EMG,2)], [0 14], 'LineWidth', 2, 'Color',[0.9412 0.9412 0.9412])
                line(app.UIAxes3,[3*size(EMG,2) 3*size(EMG,2)], [0 14], 'LineWidth', 2, 'Color',[0.9412 0.9412 0.9412])
                line(app.UIAxes3,[4*size(EMG,2) 4*size(EMG,2)], [0 14], 'LineWidth', 2, 'Color',[0.9412 0.9412 0.9412])
                app.UIAxes3.XLim = [0 size(EMGcheck,2)];
                app.UIAxes3.YLim = [0 14];
                drawnow
                
                prompt   = {'col #1', 'col #2', 'col #3', 'col #4', 'col #5'};
                name     = 'Select the bad channels';
                dlg_emg  = inputdlg(prompt, name);

                app.RecordParameters.DiscardChannels{i} = zeros(13,5);
                for j = 1:5
                    if ~isnan(str2double(dlg_emg{j}))
                        app.RecordParameters.DiscardChannels{i}(str2double(dlg_emg{j}), j) = 1;
                    end
                end
                close;
            end
            
            DisChannels = app.RecordParameters.DiscardChannels;
            Musclename = app.RecordParameters.Muscles;
            save([app.EditField_saving.Value '/EMGChecking ' datestr(datetime('now')) '.mat'],'EMG', 'DisChannels', 'Musclename');
            app.StartVisualizationCheckEMGButton.BackgroundColor = 'Green';
            app.RecordingTab.BackgroundColor = 'Green';
            app.InitializeQuattrocentoButton.BackgroundColor = [0.15 0.15 0.15];
            app.StartVisualizationCheckEMGButton.Enable = 'off';
            app.StartVisualizationChecknoiseButton.Enable = 'off';
            app.StartButton.Enable = 'off';
            app.StartMVCButton.Enable = 'off';
        end

        % Value changed function: OffsetCheckBox
        function OffsetCheckBoxValueChanged(app, event)
            if app.OffsetCheckBox.Value == 1
                            
                nwin = floor((3*str2double(app.FrequencyDropDown.Value))/app.ComParameters.nsamp);
                force = zeros(1,nwin * app.ComParameters.nsamp);
                i = 1;
                    
                app.ComParameters.ConfString(1) = app.ComParameters.ConfString(1) + 1; % Update the first byte that start the data transer
                app.ComParameters.ConfString(40) = CRC8(app.ComParameters.ConfString, 39);

                % Get the data
                open(app.ComParameters.tcpSocket);
                set(app.ComParameters.tcpSocket, 'ByteOrder', 'little-endian');
                write(app.ComParameters.tcpSocket, app.ComParameters.ConfString, 'uint8'); % Send the configuration to quattrocento
                app.DisconnectQuattrocentoButton.Enable = 'on';
                while i <= nwin
                    data = read(app.ComParameters.tcpSocket, [app.ComParameters.nchannel, app.ComParameters.nsamp], 'int16');
                    force((i-1)*app.ComParameters.nsamp+1:i*app.ComParameters.nsamp) = data(app.ComParameters.gridchannels(end)+1, :);                    
                    i = i + 1;
                end
                app.ComParameters.ConfString(1) = app.ComParameters.ConfString(1) - 1; % Update the first byte that start the data transer
                app.ComParameters.ConfString(40) = CRC8(app.ComParameters.ConfString, 39);            
                write(app.ComParameters.tcpSocket, app.ComParameters.ConfString, 'uint8');
                close(app.ComParameters.tcpSocket); % Close the communication
                flush(app.ComParameters.tcpSocket)

                app.RecordParameters.offsetforce = mean(force(str2double(app.FrequencyDropDown.Value):end));
                app.EditField_offset.Value = app.RecordParameters.offsetforce;
            else
                app.RecordParameters.offsetforce = 0;
                app.EditField_offset.Value = app.RecordParameters.offsetforce;
            end
            app.InitializeQuattrocentoButton.BackgroundColor = [0.15 0.15 0.15];
            app.StartVisualizationCheckEMGButton.Enable = 'off';
            app.StartVisualizationChecknoiseButton.Enable = 'off';
            app.StartButton.Enable = 'off';
            app.StartMVCButton.Enable = 'off';
        end

        % Button pushed function: StartMVCButton
        function StartMVCButtonPushed(app, event)
            
            trialength = str2double(app.FrequencyDropDown.Value) * app.DurationEditField.Value * 3 + str2double(app.FrequencyDropDown.Value) * app.RestEditField.Value * 2;

            MVCtrial = ones(str2double(app.FrequencyDropDown.Value) * app.DurationEditField.Value,1);
            MVCrest = zeros(str2double(app.FrequencyDropDown.Value) * app.RestEditField.Value,1);
            MVClight = [MVCtrial;MVCrest;MVCtrial;MVCrest;MVCtrial];
            
            Time = linspace(0,trialength/str2double(app.FrequencyDropDown.Value),trialength);
            EMG = zeros(64*app.RecordParameters.ngrid,trialength);
            Force = zeros(1,trialength);
            
            app.Gauge.Limits = [0 0.5];
            app.Gauge.MajorTicks = 0:0.05:0.05;
            
            nwin = floor(trialength/app.ComParameters.nsamp);
            i = 1;

            app.ComParameters.ConfString(1) = app.ComParameters.ConfString(1) + 1; % Update the first byte that start the data transer
            app.ComParameters.ConfString(40) = CRC8(app.ComParameters.ConfString, 39);

            % Get the data
            open(app.ComParameters.tcpSocket);
            set(app.ComParameters.tcpSocket, 'ByteOrder', 'little-endian');
            write(app.ComParameters.tcpSocket, app.ComParameters.ConfString, 'uint8');
            while i <= nwin
                data = read(app.ComParameters.tcpSocket, [app.ComParameters.nchannel, app.ComParameters.nsamp], 'int16');
                EMG(:,(i-1)*app.ComParameters.nsamp+1:i*app.ComParameters.nsamp) = data(app.ComParameters.gridchannels, :);
                Force((i-1)*app.ComParameters.nsamp+1:i*app.ComParameters.nsamp) = (data(app.ComParameters.gridchannels(end)+1,:) - app.RecordParameters.offsetforce);
                                
                app.Gauge.Value = abs(mean(Force((i-1)*app.ComParameters.nsamp+1:i*app.ComParameters.nsamp)));
                app.EditField_MVC.Value = num2str(Time(i*app.ComParameters.nsamp));
                if MVClight(i*app.ComParameters.nsamp) == 1
                    app.StartMVCButton.BackgroundColor = 'green';
                else
                    app.StartMVCButton.BackgroundColor = 'red';
                end
                drawnow
                i = i + 1;
            end            
            app.ComParameters.ConfString(1) = app.ComParameters.ConfString(1) - 1; % Update the first byte that start the data transer
            app.ComParameters.ConfString(40) = CRC8(app.ComParameters.ConfString, 39);            
            write(app.ComParameters.tcpSocket, app.ComParameters.ConfString, 'uint8');
            close(app.ComParameters.tcpSocket); % Close the communication
            flush(app.ComParameters.tcpSocket)

            EMG = EMG(:,str2double(app.FrequencyDropDown.Value):end);
            Force = Force(str2double(app.FrequencyDropDown.Value):end);

            app.RecordParameters.MVC = max(movmean(Force, str2double(app.FrequencyDropDown.Value)));
            MVCforce = app.RecordParameters.MVC;
            Offset_force = app.RecordParameters.offsetforce;
            DisChannels = app.RecordParameters.DiscardChannels;
            Musclename = app.RecordParameters.Muscles;
            
            save([app.EditField_saving.Value '/MVC ' datestr(datetime('now')) '.mat'], 'EMG', 'MVCforce', 'Force', 'Offset_force', 'Time', 'MVClight', 'DisChannels', 'Musclename');
            
            app.StartMVCButton.BackgroundColor = 'Green';
            app.InitializeQuattrocentoButton.BackgroundColor = [0.15 0.15 0.15];
            app.StartVisualizationCheckEMGButton.Enable = 'off';
            app.StartVisualizationChecknoiseButton.Enable = 'off';
            app.StartButton.Enable = 'off';
            app.StartMVCButton.Enable = 'off';
        end

        % Value changed function: DurationtrainingEditField
        function DurationtrainingEditFieldValueChanged(app, event)
            fsamp = str2double(app.FrequencyDropDown.Value);
            app.DecompParameters.durationtraining = app.DurationtrainingEditField.Value * fsamp + 2*15*fsamp + 2*10*fsamp;
            time = linspace(0,app.DecompParameters.durationtraining/fsamp,app.DecompParameters.durationtraining);
            rest = zeros(1,10*fsamp);
            rampup = linspace(0,app.MVCTargetEditField.Value,15*fsamp);
            rampdown = linspace(app.MVCTargetEditField.Value,0,15*fsamp);
            plateau = ones(1,app.DurationtrainingEditField.Value * fsamp) * app.MVCTargetEditField.Value;
            app.DecompParameters.target = [rest rampup plateau rampdown rest];
            plot(app.UIAxes,time,app.DecompParameters.target,'Linewidth', 2, 'Color', [0.9412 0.9412 0.9412]);
            app.UIAxes.XLim = [0 app.DecompParameters.durationtraining/fsamp];
            app.UIAxes.YLim = [0 app.MVCTargetEditField.Value + 1];
            app.UIAxes.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes.YColor = [0.9412 0.9412 0.9412];
        end

        % Value changed function: MVCTargetEditField
        function MVCTargetEditFieldValueChanged(app, event)
            fsamp = str2double(app.FrequencyDropDown.Value);
            app.DecompParameters.durationtraining = app.DurationtrainingEditField.Value * fsamp + 2*15*fsamp + 2*10*fsamp;
            time = linspace(0,app.DecompParameters.durationtraining/fsamp,app.DecompParameters.durationtraining);
            rest = zeros(1,10*fsamp);
            rampup = linspace(0,app.MVCTargetEditField.Value,15*fsamp);
            rampdown = linspace(app.MVCTargetEditField.Value,0,15*fsamp);
            plateau = ones(1,app.DurationtrainingEditField.Value * fsamp) * app.MVCTargetEditField.Value;
            app.DecompParameters.target = [rest rampup plateau rampdown rest];
            plot(app.UIAxes,time,app.DecompParameters.target,'Linewidth', 2, 'Color', [0.9412 0.9412 0.9412]);
            app.UIAxes.XLim = [0 app.DecompParameters.durationtraining/fsamp];
            app.UIAxes.YLim = [0 app.MVCTargetEditField.Value + 1];
            app.UIAxes.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes.YColor = [0.9412 0.9412 0.9412];
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            nwin = floor(app.DecompParameters.durationtraining/app.ComParameters.nsamp);
            EMG = zeros(app.RecordParameters.ngrid*64,app.DecompParameters.durationtraining);
            Force = NaN(1,app.DecompParameters.durationtraining);
            timetot = linspace(0,app.DecompParameters.durationtraining/str2double(app.FrequencyDropDown.Value),app.DecompParameters.durationtraining);

            i = 1;
            nwinbuffmax = floor(str2double(app.FrequencyDropDown.Value)*5/app.ComParameters.nsamp);
            nwin = nwin - nwinbuffmax;

            app.ComParameters.ConfString(1) = app.ComParameters.ConfString(1) + 1; % Update the first byte that start the data transer
            app.ComParameters.ConfString(40) = CRC8(app.ComParameters.ConfString, 39);

            app.UIAxes3.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes3.YColor = [0.9412 0.9412 0.9412];

            % Get the data
            open(app.ComParameters.tcpSocket);
            set(app.ComParameters.tcpSocket, 'ByteOrder', 'little-endian');
            write(app.ComParameters.tcpSocket, app.ComParameters.ConfString, 'uint8'); % Send the configuration to quattrocento
            app.DisconnectQuattrocentoButton.Enable = 'on';
            while i <= nwin
                data = read(app.ComParameters.tcpSocket, [app.ComParameters.nchannel, app.ComParameters.nsamp], 'int16');
                EMG(:,(i-1)*app.ComParameters.nsamp+1:i*app.ComParameters.nsamp) = data(app.ComParameters.gridchannels, :);
                Force(:,(i-1)*app.ComParameters.nsamp+1:i*app.ComParameters.nsamp) = (data(app.ComParameters.gridchannels(end)+1,:) - app.RecordParameters.offsetforce);
                Force(:,(i-1)*app.ComParameters.nsamp+1:i*app.ComParameters.nsamp) = movmean(Force(:,(i-1)*app.ComParameters.nsamp+1:i*app.ComParameters.nsamp),50);
                
                if i <= nwinbuffmax
                    plot(app.UIAxes3, timetot(1:str2double(app.FrequencyDropDown.Value)*10), abs(Force(1:str2double(app.FrequencyDropDown.Value)*10))/app.RecordParameters.MVC *100, ...
                    timetot(1:str2double(app.FrequencyDropDown.Value)*10), app.DecompParameters.target(1:str2double(app.FrequencyDropDown.Value)*10), ...
                    timetot(i*app.ComParameters.nsamp), abs(Force(i*app.ComParameters.nsamp))/app.RecordParameters.MVC * 100, 'o', 'MarkerSize', 20, 'LineWidth', 3, 'Color', [0.85 0.33 0.10]);
                    app.UIAxes3.XLim = [timetot(1) timetot(str2double(app.FrequencyDropDown.Value)*10)];
                    app.UIAxes3.YLim = [0 app.MVCTargetEditField.Value + 3];
                    plot(app.UIAxes, timetot, app.DecompParameters.target, timetot(i*app.ComParameters.nsamp), app.DecompParameters.target(i*app.ComParameters.nsamp), 'o', 'LineWidth', 2, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412]);
                    drawnow
                else
                    plot(app.UIAxes3, timetot((i-1)*app.ComParameters.nsamp+1-str2double(app.FrequencyDropDown.Value)*5:str2double(app.FrequencyDropDown.Value)*5+(i-1)*app.ComParameters.nsamp), abs(Force((i-1)*app.ComParameters.nsamp+1-str2double(app.FrequencyDropDown.Value)*5:str2double(app.FrequencyDropDown.Value)*5+(i-1)*app.ComParameters.nsamp))/app.RecordParameters.MVC *100, ...
                    timetot((i-1)*app.ComParameters.nsamp+1-str2double(app.FrequencyDropDown.Value)*5:str2double(app.FrequencyDropDown.Value)*5+(i-1)*app.ComParameters.nsamp), app.DecompParameters.target((i-1)*app.ComParameters.nsamp+1-str2double(app.FrequencyDropDown.Value)*5:str2double(app.FrequencyDropDown.Value)*5+(i-1)*app.ComParameters.nsamp), ...
                    timetot(i*app.ComParameters.nsamp), abs(Force(i*app.ComParameters.nsamp))/app.RecordParameters.MVC *100, 'o', 'MarkerSize', 20, 'LineWidth', 3, 'Color', [0.85 0.33 0.10]);
                    app.UIAxes3.XLim = [timetot((i-1)*app.ComParameters.nsamp+1-str2double(app.FrequencyDropDown.Value)*5) timetot((i-1)*app.ComParameters.nsamp+1+str2double(app.FrequencyDropDown.Value)*5)];
                    app.UIAxes3.YLim = [0 app.MVCTargetEditField.Value + 3];
                    plot(app.UIAxes, timetot, app.DecompParameters.target, timetot(i*app.ComParameters.nsamp), app.DecompParameters.target(i*app.ComParameters.nsamp), 'o', 'LineWidth', 2, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412]);
                    drawnow
                end
                i = i + 1;
            end            
            app.ComParameters.ConfString(1) = app.ComParameters.ConfString(1) - 1; % Update the first byte that start the data transer
            app.ComParameters.ConfString(40) = CRC8(app.ComParameters.ConfString, 39);            
            write(app.ComParameters.tcpSocket, app.ComParameters.ConfString, 'uint8');
            close(app.ComParameters.tcpSocket); % Close the communication
            flush(app.ComParameters.tcpSocket)

            app.InitializeQuattrocentoButton.BackgroundColor = [0.15 0.15 0.15];
            app.StartVisualizationCheckEMGButton.Enable = 'off';
            app.StartVisualizationChecknoiseButton.Enable = 'off';
            app.StartButton.Enable = 'off';
            app.StartMVCButton.Enable = 'off';

            % Decomposition for training
            f = waitbar(0,'Decomposition...');
            nbextchan = 1500;
            
            offsetMU = 0;

            for i = 1:app.RecordParameters.ngrid
                maskEMG = reshape(app.RecordParameters.DiscardChannels{i},[],1);
                maskEMG = maskEMG(1:64);
                SIG = EMG((i-1)*64+1:i*64,25*str2double(app.FrequencyDropDown.Value):25*str2double(app.FrequencyDropDown.Value)+app.DurationtrainingEditField.Value*str2double(app.FrequencyDropDown.Value));
                SIG = SIG(maskEMG==0,:);

                % Signal Extension
                exFactor = round(nbextchan/size(SIG,1));
                eSIG = extend(SIG,exFactor);

                % Signal Whitening
                [E, D] = pcaesig(eSIG);
                [wSIG, ~, dewhiteningMatrix] = whiteesig(eSIG, E, D);
                
                % Removing the edges
                wSIG = wSIG(:,round(str2double(app.FrequencyDropDown.Value)):end-round(str2double(app.FrequencyDropDown.Value)));

                % FastICA
                % Initialize matrix B (n x m) n: separation vectors, m: iterations 
                % Initialize matrix MUFilters to only save the reliable filters
                % Intialize SIL and PNR
                B = zeros(size(wSIG,1), app.NbIterationsEditField.Value); % all separation vectors
                MUFilters = zeros(size(wSIG,1), app.NbIterationsEditField.Value); % only reliable vectors
                SIL = zeros(1,app.NbIterationsEditField.Value);
                COV = zeros(1,app.NbIterationsEditField.Value);

                % Find the index where the square of the summed whitened vectors is
                % maximized and initialize W with the whitened observations at this time
                idx1 = zeros(1,app.NbIterationsEditField.Value);

                for j = 1:app.NbIterationsEditField.Value
                    if j == 1
                        X = wSIG; % Initialize X (whitened signal), then X: residual
                        actind = sum(X,1).^2;
                        [~, idx1(j)] = max(actind);
                        w = X(:, idx1(j)); % Initialize w
                    else
                        actind(idx1(j-1)) = 0; % remove the previous vector
                        [~, idx1(j)] = max(actind);
                        w = X(:, idx1(j)); % Initialize w
                    end
                    
                    w = w - B * B' * w; % Orthogonalization
                    w = w / norm(w); % Normalization
                    
                    %       3a: Fixed point algorithm (end when sparsness is maximized)
                    maxiter = 500; % max number of iterations for the fixed point algorithm
                    w = fixedpointalg(w, X, B , maxiter, 'logcosh');
                    
                    % Step 4: Minimization of the CoV of discharge times (end when CoV is
                    % minimized)
                    % Initialize CoV (variation of interspike intervals, %) Step 4a => 4e
                    [~, spikes] = getspikes(w, X, str2double(app.FrequencyDropDown.Value));
                    
                    if length(spikes) > 10 % If the number of peaks is low, skip the second algorithm
                        ISI = diff(spikes/str2double(app.FrequencyDropDown.Value)); % Interspike interval
                        COV(j) = std(ISI)/mean(ISI); % Coefficient of variation
                        Wini = sum(X(:,spikes),2); % update W by summing the spikes
                                       
                                % Minimization of the CoV of discharge times (end when CoV is
                        % minimized)
                        [MUFilters(:,j), ~, COV(j)] = minimizeCOVISI(Wini, X, COV(j), str2double(app.FrequencyDropDown.Value));
                        B(:,j) = w;
                    
                        % Calculate SIL values
                        [~, ~, SIL(j)] = calcSIL(X, MUFilters(:,j), str2double(app.FrequencyDropDown.Value));
                    else
                        B(:,j) = w;
                    end
                    tit = ['Ite : ' num2str(j) ' - SIL : ' num2str(round(SIL(j),2)) ' - COV : ' num2str(round(COV(j),2))];
                    waitbar((j+app.NbIterationsEditField.Value*(i-1))/(app.NbIterationsEditField.Value*app.RecordParameters.ngrid),f,tit);                               
                end

                waitbar(i/app.RecordParameters.ngrid,f,['Batch processing grid #' num2str(i) '...']);
                % Filter out MUfilters below the SIL threshold
                MUFilters(:,SIL < app.SILthresholdEditField.Value) = [];
                COV(SIL < app.SILthresholdEditField.Value) = [];
                SIL(SIL < app.SILthresholdEditField.Value) = [];
                MUFilters(:,COV > app.COVthresholdEditField.Value) = [];
                MUFilters = dewhiteningMatrix * MUFilters;
                size(MUFilters);

                % Get the pulse train for the entire signal
                [PulseT, Distime, ~] = getPulseT(EMG((i-1)*64+1:i*64,:), maskEMG, MUFilters, str2double(app.FrequencyDropDown.Value));

                % Remove duplicates
                close(f)
                [~, Distime] = remduplicates(PulseT, Distime, Distime, exFactor, 0.3);

                % Get the decomposition parameters for the biofeedback
                app.DecompParameters.MUFilters{i} = getMUfilters(EMG((i-1)*64+1:i*64,:), maskEMG, Distime);
                [PulseT, Distime, ~] = getPulseT(EMG((i-1)*64+1:i*64,:), maskEMG, app.DecompParameters.MUFilters{i}, str2double(app.FrequencyDropDown.Value));
                [app.DecompParameters.extensionfactor(i), app.DecompParameters.iReSIG{i}, app.DecompParameters.norm{i}, app.DecompParameters.centroid{i}] = getonlineparameters(EMG((i-1)*64+1:i*64,:), maskEMG, app.DecompParameters.MUFilters{i}, str2double(app.FrequencyDropDown.Value));

                time = linspace(0,size(PulseT,2)/str2double(app.FrequencyDropDown.Value),size(PulseT,2));

                for k = 1:length(Distime)
                    plot(app.UIAxes3, time, PulseT(k,:)+k+offsetMU, time(Distime{k}), PulseT(k,Distime{k})+k+offsetMU, 'o', 'LineWidth', 1.5, 'MarkerSize', 10, 'Color', [0.9412 0.9412 0.9412]);
                    hold(app.UIAxes3, 'on')
                    signal.Dischargetimes{i,k} = Distime{k};
                    app.DecompParameters.DR{i}(k) = mean(1./(diff(Distime{k})/str2double(app.FrequencyDropDown.Value)));
                end
                    
                offsetMU = offsetMU + length(Distime) + 1;
                    
                app.UIAxes3.XLim = [time(1) time(end)];
                app.UIAxes3.YLim = [0 offsetMU];

                signal.EMGmask{i} = maskEMG;
                app.DecompParameters.EMGmask{i} = maskEMG;
                signal.Pulsetrain{i} = PulseT;
                signal.gridname{i} = 'GR08MM1305';
                signal.SIL{i} = SIL;
                signal.COV{i} = COV;

                clearvars Distime PulseT B w wSIG X E D eSIG SIG maskEMG SIL COV

            end
            
            hold(app.UIAxes3, 'off')

            signal.data = EMG;
            signal.target = app.DecompParameters.target;
            signal.path = Force;
            signal.fsamp = str2double(app.FrequencyDropDown.Value);
            signal.muscle = app.RecordParameters.Muscles;
            signal.nChan = size(EMG,1);
            signal.ngrid = app.RecordParameters.ngrid; 
            save([app.EditField_saving.Value '/Training_' num2str(app.MVCTargetEditField.Value) '.mat'], 'signal');

            for i = 1:app.RecordParameters.ngrid
                % Update the list of the MUs for the biofeedback
                app.XaxisdisplayedDropDown.Items = {};
                app.YaxisdisplayedDropDown.Items = {};
                for j = 1:size(signal.Pulsetrain{i},1)
                    app.XaxisdisplayedDropDown.Items = horzcat(app.XaxisdisplayedDropDown.Items, {['Grid_', num2str(i), '_MU_' , num2str(j)]});
                    app.YaxisdisplayedDropDown.Items = horzcat(app.YaxisdisplayedDropDown.Items, {['Grid_', num2str(i), '_MU_' , num2str(j)]});
                end
            end
            app.XaxisdisplayedDropDown.Enable = 'on';
            app.YaxisdisplayedDropDown.Enable = 'on';
            app.StartButton.BackgroundColor = 'Green';
        end

        % Value changed function: NbiterationsEditField
        function NbiterationsEditFieldValueChanged(app, event)
            fsamp = str2double(app.FrequencyDropDown.Value);
            app.OnlineParameters.durationonline = (app.DurationplateauEditField.Value * fsamp + 2*app.DurationrampEditField.Value*fsamp + 2*app.DurationrestEditField.Value*fsamp) * app.NbiterationsEditField.Value;
            time = linspace(0,app.OnlineParameters.durationonline/fsamp,app.OnlineParameters.durationonline);
            rest = zeros(1,app.DurationrestEditField.Value*fsamp);
            rampup = linspace(0,app.MVCtargetEditField.Value,app.DurationrampEditField.Value*fsamp);
            rampdown = linspace(app.MVCtargetEditField.Value,0,app.DurationrampEditField.Value*fsamp);
            plateau = ones(1,app.DurationplateauEditField.Value * fsamp) * app.MVCtargetEditField.Value;
            app.OnlineParameters.targetonline = [];
            for i = 1:app.NbiterationsEditField.Value
                app.OnlineParameters.targetonline = [app.OnlineParameters.targetonline rest rampup plateau rampdown rest];
            end
            plot(app.UIAxes2,time,app.OnlineParameters.targetonline,'Linewidth', 2, 'Color', [0.9412 0.9412 0.9412]);
            app.UIAxes2.XLim = [-0.01 app.OnlineParameters.durationonline/fsamp];
            app.UIAxes2.YLim = [-0.01 app.MVCtargetEditField.Value + 1];
            app.UIAxes2.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes2.YColor = [0.9412 0.9412 0.9412];
        end

        % Value changed function: DurationrestEditField
        function DurationrestEditFieldValueChanged(app, event)
            fsamp = str2double(app.FrequencyDropDown.Value);
            app.OnlineParameters.durationonline = (app.DurationplateauEditField.Value * fsamp + 2*app.DurationrampEditField.Value*fsamp + 2*app.DurationrestEditField.Value*fsamp) * app.NbiterationsEditField.Value;
            time = linspace(0,app.OnlineParameters.durationonline/fsamp,app.OnlineParameters.durationonline);
            rest = zeros(1,app.DurationrestEditField.Value*fsamp);
            rampup = linspace(0,app.MVCtargetEditField.Value,app.DurationrampEditField.Value*fsamp);
            rampdown = linspace(app.MVCtargetEditField.Value,0,app.DurationrampEditField.Value*fsamp);
            plateau = ones(1,app.DurationplateauEditField.Value * fsamp) * app.MVCtargetEditField.Value;
            app.OnlineParameters.targetonline = [];
            for i = 1:app.NbiterationsEditField.Value
                app.OnlineParameters.targetonline = [app.OnlineParameters.targetonline rest rampup plateau rampdown rest];
            end
            plot(app.UIAxes2,time,app.OnlineParameters.targetonline,'Linewidth', 2, 'Color', [0.9412 0.9412 0.9412]);
            app.UIAxes2.XLim = [-0.01 app.OnlineParameters.durationonline/fsamp];
            app.UIAxes2.YLim = [-0.01 app.MVCtargetEditField.Value + 1];  
            app.UIAxes2.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes2.YColor = [0.9412 0.9412 0.9412];
        end

        % Value changed function: DurationplateauEditField
        function DurationplateauEditFieldValueChanged(app, event)
            fsamp = str2double(app.FrequencyDropDown.Value);
            app.OnlineParameters.durationonline = (app.DurationplateauEditField.Value * fsamp + 2*app.DurationrampEditField.Value*fsamp + 2*app.DurationrestEditField.Value*fsamp) * app.NbiterationsEditField.Value;
            time = linspace(0,app.OnlineParameters.durationonline/fsamp,app.OnlineParameters.durationonline);
            rest = zeros(1,app.DurationrestEditField.Value*fsamp);
            rampup = linspace(0,app.MVCtargetEditField.Value,app.DurationrampEditField.Value*fsamp);
            rampdown = linspace(app.MVCtargetEditField.Value,0,app.DurationrampEditField.Value*fsamp);
            plateau = ones(1,app.DurationplateauEditField.Value * fsamp) * app.MVCtargetEditField.Value;
            app.OnlineParameters.targetonline = [];
            for i = 1:app.NbiterationsEditField.Value
                app.OnlineParameters.targetonline = [app.OnlineParameters.targetonline rest rampup plateau rampdown rest];
            end
            plot(app.UIAxes2,time,app.OnlineParameters.targetonline,'Linewidth', 2, 'Color', [0.9412 0.9412 0.9412]);
            app.UIAxes2.XLim = [-0.01 app.OnlineParameters.durationonline/fsamp];
            app.UIAxes2.YLim = [-0.01 app.MVCtargetEditField.Value + 1];
            app.UIAxes2.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes2.YColor = [0.9412 0.9412 0.9412];
        end

        % Value changed function: DurationrampEditField
        function DurationrampEditFieldValueChanged(app, event)
            fsamp = str2double(app.FrequencyDropDown.Value);
            app.OnlineParameters.durationonline = (app.DurationplateauEditField.Value * fsamp + 2*app.DurationrampEditField.Value*fsamp + 2*app.DurationrestEditField.Value*fsamp) * app.NbiterationsEditField.Value;
            time = linspace(0,app.OnlineParameters.durationonline/fsamp,app.OnlineParameters.durationonline);
            rest = zeros(1,app.DurationrestEditField.Value*fsamp);
            rampup = linspace(0,app.MVCtargetEditField.Value,app.DurationrampEditField.Value*fsamp);
            rampdown = linspace(app.MVCtargetEditField.Value,0,app.DurationrampEditField.Value*fsamp);
            plateau = ones(1,app.DurationplateauEditField.Value * fsamp) * app.MVCtargetEditField.Value;
            app.OnlineParameters.targetonline = [];
            for i = 1:app.NbiterationsEditField.Value
                app.OnlineParameters.targetonline = [app.OnlineParameters.targetonline rest rampup plateau rampdown rest];
            end
            plot(app.UIAxes2,time,app.OnlineParameters.targetonline,'Linewidth', 2, 'Color', [0.9412 0.9412 0.9412]);
            app.UIAxes2.XLim = [-0.01 app.OnlineParameters.durationonline/fsamp];
            app.UIAxes2.YLim = [-0.01 app.MVCtargetEditField.Value + 1];
            app.UIAxes2.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes2.YColor = [0.9412 0.9412 0.9412];
        end

        % Value changed function: MVCtargetEditField
        function MVCtargetEditFieldValueChanged(app, event)
            fsamp = str2double(app.FrequencyDropDown.Value);
            app.OnlineParameters.durationonline = (app.DurationplateauEditField.Value * fsamp + 2 * app.DurationrampEditField.Value * fsamp + 2*app.DurationrestEditField.Value*fsamp) * app.NbiterationsEditField.Value;
            app.NbiterationsEditField.Value
            time = linspace(0,app.OnlineParameters.durationonline/fsamp,app.OnlineParameters.durationonline);
            rest = zeros(1,app.DurationrestEditField.Value * fsamp);
            rampup = linspace(0,app.MVCtargetEditField.Value,app.DurationrampEditField.Value * fsamp);
            rampdown = linspace(app.MVCtargetEditField.Value,0,app.DurationrampEditField.Value * fsamp);
            plateau = ones(1,app.DurationplateauEditField.Value * fsamp) * app.MVCtargetEditField.Value;
            app.OnlineParameters.targetonline = [];
            for i = 1:app.NbiterationsEditField.Value
                app.OnlineParameters.targetonline = [app.OnlineParameters.targetonline rest rampup plateau rampdown rest];
            end
            plot(app.UIAxes2,time,app.OnlineParameters.targetonline,'Linewidth', 2, 'Color', [0.9412 0.9412 0.9412]);
            app.UIAxes2.XLim = [-0.01 app.OnlineParameters.durationonline/fsamp];
            app.UIAxes2.YLim = [-0.01 app.MVCtargetEditField.Value + 1];
            app.UIAxes2.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes2.YColor = [0.9412 0.9412 0.9412];
        end

        % Button pushed function: StartQuadrantwithCSTsButton
        function StartQuadrantwithCSTsButtonPushed(app, event)
            C1 = strsplit(app.XaxisdisplayedDropDown.Value,'_');
            idxmuscle1 = str2double(C1{2});

            nwin = floor(app.OnlineParameters.durationonline/app.ComParameters.nsamp);
            EMG = zeros(app.RecordParameters.ngrid*64,app.OnlineParameters.durationonline);
            Force = NaN(1,app.OnlineParameters.durationonline);

            EMGtmp{1} = zeros(64,app.ComParameters.nsamp);
            esample2{1} = zeros((64-sum(app.DecompParameters.EMGmask{idxmuscle1}))*app.DecompParameters.extensionfactor(idxmuscle1),app.DecompParameters.extensionfactor(idxmuscle1)-1);
            Distime{1} = zeros(nwin*app.ComParameters.nsamp, size(app.DecompParameters.MUFilters{idxmuscle1},2));
            signal.Pulsetrain{1} = zeros(size(app.DecompParameters.MUFilters{idxmuscle1},2), app.ComParameters.nsamp*nwin);
            noise_centroids{1} = ones(app.ComParameters.nsamp,size(app.DecompParameters.MUFilters{idxmuscle1},2)) .* (app.DecompParameters.centroid{idxmuscle1}(:,1)');
            spike_centroids{1} = ones(app.ComParameters.nsamp,size(app.DecompParameters.MUFilters{idxmuscle1},2)) .* (app.DecompParameters.centroid{idxmuscle1}(:,2)');
            MUfilt{1} = app.DecompParameters.MUFilters{idxmuscle1}' * app.DecompParameters.iReSIG{idxmuscle1};

            app.ComParameters.ConfString(1) = app.ComParameters.ConfString(1) + 1; % Update the first byte that start the data transer
            app.ComParameters.ConfString(40) = CRC8(app.ComParameters.ConfString, 39);

            % Step 2 - Online decomposition
            k = 1;
            DR = zeros(nwin, 2);
            forcefb = zeros(1,nwin);
            feedback = zeros(1,nwin);
            idxhMU = floor(size(app.DecompParameters.MUFilters{idxmuscle1},2)/2);
            
            plot(app.UIAxes4, DR(k:k+7,1) , DR(k:k+7,2), DR(k,1) , DR(k,2),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
            plot(app.UIAxes5, 1, forcefb(k),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
            line(app.UIAxes5,[0 2], [feedback(k)*0.95 feedback(k)*0.95],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
            line(app.UIAxes5,[0 2], [feedback(k)*1.05 feedback(k)*1.05],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
            app.UIAxes4.XLim = [0 sum(app.DecompParameters.DR{idxmuscle1}(1:idxhMU))*1.2];
            app.UIAxes4.YLim = [0 sum(app.DecompParameters.DR{idxmuscle1}(idxhMU+1:end))*1.2];
            app.UIAxes5.XLim = [0 2];
            app.UIAxes5.YLim = [0 max(app.OnlineParameters.targetonline)*1.25];
            app.UIAxes4.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes4.YColor = [0.9412 0.9412 0.9412];
            app.UIAxes5.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes5.YColor = [0.9412 0.9412 0.9412];

            open(app.ComParameters.tcpSocket);
            set(app.ComParameters.tcpSocket, 'ByteOrder', 'little-endian');
            write(app.ComParameters.tcpSocket, app.ComParameters.ConfString, 'uint8'); % Send the configuration to quattrocento
            app.DisconnectQuattrocentoButton.Enable = 'on';
            while k <= nwin
                data = read(app.ComParameters.tcpSocket, [app.ComParameters.nchannel, app.ComParameters.nsamp], 'int16');
                EMG(:,(k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp) = data(app.ComParameters.gridchannels((idxmuscle1-1)*64+1:idxmuscle1*64), :);
                Force(:,(k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp) = (data(app.ComParameters.gridchannels(end)+1,:) - app.RecordParameters.offsetforce);
                Force(:,(k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp) = movmean(Force(:,(k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp),50);
                forcefb(k) = mean(Force((k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp));
                feedback(k) = mean(app.OnlineParameters.targetonline((k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp));
                
                EMGtmp{1} = EMG(app.DecompParameters.EMGmask{idxmuscle1}==0, (k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp);
            
                [signal.Pulsetrain{1}(:,(k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp), Distime{1}((k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp, :), esample2{1}] = getspikesonline(EMGtmp{1}, app.DecompParameters.extensionfactor(idxmuscle1), esample2{1}, MUfilt{1}, app.DecompParameters.norm{idxmuscle1}, noise_centroids{1}, spike_centroids{1}, app.ComParameters.nsamp, str2double(app.FrequencyDropDown.Value));
                
                if k < 8
                    DR(k,1) = sum(Distime{1}((k-1)*app.ComParameters.nsamp+1:8*app.ComParameters.nsamp, 1:idxhMU) > 0, 'all');
                    DR(k,2) = sum(Distime{1}((k-1)*app.ComParameters.nsamp+1:8*app.ComParameters.nsamp, idxhMU+1:end) > 0, 'all');    
                    plot(app.UIAxes4, DR(k:k+7,1) , DR(k:k+7,2), DR(k,1) , DR(k,2),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
                    plot(app.UIAxes5, 1, forcefb(k),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
                    line(app.UIAxes5,[0 2], [feedback(k)*0.95 feedback(k)*0.95],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                    line(app.UIAxes5,[0 2], [feedback(k)*1.05 feedback(k)*1.05],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                else
                    DR(k,1) = sum(Distime{1}((k-7)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp, 1:idxhMU) > 0, 'all');
                    DR(k,2) = sum(Distime{1}((k-7)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp, idxhMU+1:end) > 0, 'all');
                    DR(k,:) = mean(DR(k-3:k,:), 1);
                    plot(app.UIAxes4, DR(k-7:k,1) , DR(k-7:k,2), DR(k,1) , DR(k,2),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
                    plot(app.UIAxes5, 1, forcefb(k),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
                    line(app.UIAxes5,[0 2], [feedback(k)*0.95 feedback(k)*0.95],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                    line(app.UIAxes5,[0 2], [feedback(k)*1.05 feedback(k)*1.05],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                end
                app.UIAxes4.XLim = [0 sum(app.DecompParameters.DR{idxmuscle1}(1:idxhMU))*1.2];
                app.UIAxes4.YLim = [0 sum(app.DecompParameters.DR{idxmuscle1}(idxhMU+1:end))*1.2];
                app.UIAxes5.YLim = [0 max(app.OnlineParameters.targetonline)*1.25];
                drawnow
                k = k+1;
            end
            app.ComParameters.ConfString(1) = app.ComParameters.ConfString(1) - 1; % Update the first byte that start the data transer
            app.ComParameters.ConfString(40) = CRC8(app.ComParameters.ConfString, 39);            
            write(app.ComParameters.tcpSocket, app.ComParameters.ConfString, 'uint8');
            close(app.ComParameters.tcpSocket); % Close the communication
            flush(app.ComParameters.tcpSocket)

            app.InitializeQuattrocentoButton_2.BackgroundColor = [0.15 0.15 0.15];

            signal.data = EMG;
            signal.target = app.OnlineParameters.targetonline;
            signal.path = Force;
            signal.fsamp = str2double(app.FrequencyDropDown.Value);
            signal.muscle = app.RecordParameters.Muscles{idxmuscle1};
            signal.nChan = size(EMG,1);
            signal.ngrid = app.RecordParameters.ngrid; 
            signal.EMGmask{1} = app.DecompParameters.EMGmask{idxmuscle1};
            signal.gridname{1} = 'GR08MM1305';
            signal.feedback{1} = 'Quadrant_CST';

            for k = 1:size(Distime{1},2)
                signal.Dischargetimes{k} = find(Distime{1}(:,k) > 0);
            end

            save([app.EditField_saving.Value '/Online ' datestr(datetime('now')) '.mat'], 'signal');
        end

        % Button pushed function: StartQuadrantwithMUsButton
        function StartQuadrantwithMUsButtonPushed(app, event)
            C1 = strsplit(app.XaxisdisplayedDropDown.Value,'_');
            C2 = strsplit(app.YaxisdisplayedDropDown.Value,'_');

            idxmuscle1 = str2double(C1{2});
            idxmuscle2 = str2double(C2{2});
            idxmu1 = str2double(C1{4});
            idxmu2 = str2double(C2{4});

            nwin = floor(app.OnlineParameters.durationonline/app.ComParameters.nsamp);
            Force = NaN(1,app.OnlineParameters.durationonline);

            EMG{1} = zeros(app.RecordParameters.ngrid*64,app.OnlineParameters.durationonline);
            EMGtmp{1} = zeros(64,app.ComParameters.nsamp);
            esample2{1} = zeros((64-sum(app.DecompParameters.EMGmask{idxmuscle1}))*app.DecompParameters.extensionfactor(idxmuscle1),app.DecompParameters.extensionfactor(idxmuscle1)-1);
            Distime{1} = zeros(nwin*app.ComParameters.nsamp, size(app.DecompParameters.MUFilters{idxmuscle1},2));
            signal.Pulsetrain{1} = zeros(size(app.DecompParameters.MUFilters{idxmuscle1},2), app.ComParameters.nsamp*nwin);
            noise_centroids{1} = ones(app.ComParameters.nsamp,size(app.DecompParameters.MUFilters{idxmuscle1},2)) .* (app.DecompParameters.centroid{idxmuscle1}(:,1)');
            spike_centroids{1} = ones(app.ComParameters.nsamp,size(app.DecompParameters.MUFilters{idxmuscle1},2)) .* (app.DecompParameters.centroid{idxmuscle1}(:,2)');
            MUfilt{1} = app.DecompParameters.MUFilters{idxmuscle1}' * app.DecompParameters.iReSIG{idxmuscle1};

            EMG{2} = zeros(app.RecordParameters.ngrid*64,app.OnlineParameters.durationonline);
            EMGtmp{2} = zeros(64,app.ComParameters.nsamp);
            esample2{2} = zeros((64-sum(app.DecompParameters.EMGmask{idxmuscle2}))*app.DecompParameters.extensionfactor(idxmuscle2),app.DecompParameters.extensionfactor(idxmuscle2)-1);
            Distime{2} = zeros(nwin*app.ComParameters.nsamp, size(app.DecompParameters.MUFilters{idxmuscle2},2));
            signal.Pulsetrain{2} = zeros(size(app.DecompParameters.MUFilters{idxmuscle2},2), app.ComParameters.nsamp*nwin);
            noise_centroids{2} = ones(app.ComParameters.nsamp,size(app.DecompParameters.MUFilters{idxmuscle2},2)) .* (app.DecompParameters.centroid{idxmuscle2}(:,1)');
            spike_centroids{2} = ones(app.ComParameters.nsamp,size(app.DecompParameters.MUFilters{idxmuscle2},2)) .* (app.DecompParameters.centroid{idxmuscle2}(:,2)');
            MUfilt{2} = app.DecompParameters.MUFilters{idxmuscle2}' * app.DecompParameters.iReSIG{idxmuscle2};

            app.ComParameters.ConfString(1) = app.ComParameters.ConfString(1) + 1; % Update the first byte that start the data transer
            app.ComParameters.ConfString(40) = CRC8(app.ComParameters.ConfString, 39);

            % Step 2 - Online decomposition
            k = 1;
            DR{1} = zeros(nwin, size(app.DecompParameters.MUFilters{idxmuscle1},2));
            DR{2} = zeros(nwin, size(app.DecompParameters.MUFilters{idxmuscle2},2));
            forcefb = zeros(1,nwin);
            feedback = zeros(1,nwin);
            
            plot(app.UIAxes4, DR{1}(k:k+7,idxmu1) , DR{2}(k:k+7,idxmu2), DR{1}(k,idxmu1) , DR{2}(k,idxmu2),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
            plot(app.UIAxes5, 1, forcefb(k),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
            line(app.UIAxes5,[0 2], [feedback(k)*0.95 feedback(k)*0.95],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
            line(app.UIAxes5,[0 2], [feedback(k)*1.05 feedback(k)*1.05],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
            
            app.UIAxes4.XLim = [0 app.DecompParameters.DR{idxmuscle1}(idxmu1)*1.2];
            app.UIAxes4.YLim = [0 app.DecompParameters.DR{idxmuscle2}(idxmu2)*1.2];
            app.UIAxes5.XLim = [0 2];
            app.UIAxes5.YLim = [0 max(app.OnlineParameters.targetonline)*1.25];
            app.UIAxes4.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes4.YColor = [0.9412 0.9412 0.9412];
            app.UIAxes5.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes5.YColor = [0.9412 0.9412 0.9412];

            open(app.ComParameters.tcpSocket);
            set(app.ComParameters.tcpSocket, 'ByteOrder', 'little-endian');
            write(app.ComParameters.tcpSocket, app.ComParameters.ConfString, 'uint8'); % Send the configuration to quattrocento
            app.DisconnectQuattrocentoButton.Enable = 'on';
            while k <= nwin
                data = read(app.ComParameters.tcpSocket, [app.ComParameters.nchannel, app.ComParameters.nsamp], 'int16');
                Force(:,(k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp) = (data(app.ComParameters.gridchannels(end)+1,:) - app.RecordParameters.offsetforce);
                Force(:,(k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp) = movmean(Force(:,(k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp),50);
                forcefb(k) = mean(Force((k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp));
                feedback(k) = mean(app.OnlineParameters.targetonline((k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp));
                
                EMG{1}(:,(k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp) = data(app.ComParameters.gridchannels((idxmuscle1-1)*64+1:idxmuscle1*64), :);
                EMGtmp{1} = EMG{1}(app.DecompParameters.EMGmask{idxmuscle1}==0, (k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp);
                [signal.Pulsetrain{1}(:,(k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp), Distime{1}((k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp, :), esample2{1}] = getspikesonline(EMGtmp{1}, app.DecompParameters.extensionfactor(idxmuscle1), esample2{1}, MUfilt{1}, app.DecompParameters.norm{idxmuscle1}, noise_centroids{1}, spike_centroids{1}, app.ComParameters.nsamp, str2double(app.FrequencyDropDown.Value));
                
                EMG{2}(:,(k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp) = data(app.ComParameters.gridchannels((idxmuscle1-1)*64+1:idxmuscle1*64), :);
                EMGtmp{2} = EMG{2}(app.DecompParameters.EMGmask{idxmuscle2}==0, (k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp);
                [signal.Pulsetrain{2}(:,(k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp), Distime{2}((k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp, :), esample2{2}] = getspikesonline(EMGtmp{2}, app.DecompParameters.extensionfactor(idxmuscle2), esample2{2}, MUfilt{2}, app.DecompParameters.norm{idxmuscle2}, noise_centroids{2}, spike_centroids{2}, app.ComParameters.nsamp, str2double(app.FrequencyDropDown.Value));

                if k < 8
                    DR{1}(k,:) = sum(Distime{1}((k-1)*app.ComParameters.nsamp+1:8*app.ComParameters.nsamp, :) > 0, 1);
                    DR{2}(k,:) = sum(Distime{2}((k-1)*app.ComParameters.nsamp+1:8*app.ComParameters.nsamp, :) > 0, 1);    
                    plot(app.UIAxes4, DR{1}(k:k+7,idxmu1) , DR{2}(k:k+7,idxmu2), DR{1}(k,idxmu1) , DR{2}(k,idxmu2),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
                    plot(app.UIAxes5, 1, forcefb(k),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
                    line(app.UIAxes5,[0 2], [feedback(k)*0.95 feedback(k)*0.95],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                    line(app.UIAxes5,[0 2], [feedback(k)*1.05 feedback(k)*1.05],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                else
                    DR{1}(k,:) = sum(Distime{1}((k-7)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp, :) > 0, 1);
                    DR{2}(k,:) = sum(Distime{2}((k-7)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp, :) > 0, 1);
                    DR{1}(k,:) = mean(DR{1}(k-3:k,:), 1);
                    DR{2}(k,:) = mean(DR{2}(k-3:k,:), 1);
                    plot(app.UIAxes4, DR{1}(k-7:k,idxmu1) , DR{2}(k-7:k,idxmu2), DR{1}(k,idxmu1) , DR{2}(k,idxmu2),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
                    plot(app.UIAxes5, 1, forcefb(k),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
                    line(app.UIAxes5,[0 2], [feedback(k)*0.95 feedback(k)*0.95],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                    line(app.UIAxes5,[0 2], [feedback(k)*1.05 feedback(k)*1.05],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                end
                app.UIAxes4.XLim = [0 sum(app.DecompParameters.DR{idxmuscle1}(idxmu1))*1.2];
                app.UIAxes4.YLim = [0 sum(app.DecompParameters.DR{idxmuscle2}(idxmu2))*1.2];
                app.UIAxes5.YLim = [0 max(app.OnlineParameters.targetonline)*1.25];
                drawnow
                k = k+1;
            end
            app.ComParameters.ConfString(1) = app.ComParameters.ConfString(1) - 1; % Update the first byte that start the data transer
            app.ComParameters.ConfString(40) = CRC8(app.ComParameters.ConfString, 39);            
            write(app.ComParameters.tcpSocket, app.ComParameters.ConfString, 'uint8');
            close(app.ComParameters.tcpSocket); % Close the communication
            flush(app.ComParameters.tcpSocket)

            app.InitializeQuattrocentoButton_2.BackgroundColor = [0.15 0.15 0.15];

            signal.data = [];
            signal.target = app.OnlineParameters.targetonline;
            signal.path = Force;
            signal.fsamp = str2double(app.FrequencyDropDown.Value);
            signal.muscle = app.RecordParameters.Muscles;
            signal.nChan = size(EMG,1);
            signal.ngrid = app.RecordParameters.ngrid; 
            signal.EMGmask{1} = app.DecompParameters.EMGmask{idxmuscle1};
            signal.gridname{1} = 'GR08MM1305';
            signal.feedback{1} = 'Quadrant_MU';

            for i = 1:2
                for k = 1:size(Distime{i},2)
                    signal.Dischargetimes{i,k} = find(Distime{i}(:,k) > 0);
                end
                signal.data = [signal.data; EMG{i}];
            end

            save([app.EditField_saving.Value '/Online ' datestr(datetime('now')) '.mat'], 'signal');
        end

        % Button pushed function: StartDRofalltheMUsButton
        function StartDRofalltheMUsButtonPushed(app, event)
            C1 = strsplit(app.XaxisdisplayedDropDown.Value,'_');
            idxmuscle1 = str2double(C1{2});

            nwin = floor(app.OnlineParameters.durationonline/app.ComParameters.nsamp);
            EMG = zeros(app.RecordParameters.ngrid*64,app.OnlineParameters.durationonline);
            Force = NaN(1,app.OnlineParameters.durationonline);
            Time = linspace(0,size(EMG,2)/str2double(app.FrequencyDropDown.Value), nwin);

            EMGtmp{1} = zeros(64,app.ComParameters.nsamp);
            esample2{1} = zeros((64-sum(app.DecompParameters.EMGmask{idxmuscle1}))*app.DecompParameters.extensionfactor(idxmuscle1),app.DecompParameters.extensionfactor(idxmuscle1)-1);
            Distime{1} = zeros(nwin*app.ComParameters.nsamp, size(app.DecompParameters.MUFilters{idxmuscle1},2));
            signal.Pulsetrain{1} = zeros(size(app.DecompParameters.MUFilters{idxmuscle1},2), app.ComParameters.nsamp*nwin);
            noise_centroids{1} = ones(app.ComParameters.nsamp,size(app.DecompParameters.MUFilters{idxmuscle1},2)) .* (app.DecompParameters.centroid{idxmuscle1}(:,1)');
            spike_centroids{1} = ones(app.ComParameters.nsamp,size(app.DecompParameters.MUFilters{idxmuscle1},2)) .* (app.DecompParameters.centroid{idxmuscle1}(:,2)');
            MUfilt{1} = app.DecompParameters.MUFilters{idxmuscle1}' * app.DecompParameters.iReSIG{idxmuscle1};

            app.ComParameters.ConfString(1) = app.ComParameters.ConfString(1) + 1; % Update the first byte that start the data transer
            app.ComParameters.ConfString(40) = CRC8(app.ComParameters.ConfString, 39);

            k = 1;
            DR = NaN(nwin, size(app.DecompParameters.MUFilters{idxmuscle1},2));
            forcefb = zeros(1,nwin);
            feedback = zeros(1,nwin);

            plot(app.UIAxes4, Time(1:80), DR(1:80,:), Time(k), DR(k,:), 'o', 'LineWidth', 4, 'MarkerSize', 20)
            plot(app.UIAxes5, 1, forcefb(k),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
            line(app.UIAxes5,[0 2], [feedback(k)*0.95 feedback(k)*0.95],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
            line(app.UIAxes5,[0 2], [feedback(k)*1.05 feedback(k)*1.05],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
            
            app.UIAxes4.XLim = [Time(1) Time(80)];
            app.UIAxes4.YLim = [0 max(app.DecompParameters.DR{idxmuscle1})*1.2];
            app.UIAxes5.XLim = [0 2];
            app.UIAxes5.YLim = [0 max(app.OnlineParameters.targetonline)*1.25];
            app.UIAxes4.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes4.YColor = [0.9412 0.9412 0.9412];
            app.UIAxes5.XColor = [0.9412 0.9412 0.9412];
            app.UIAxes5.YColor = [0.9412 0.9412 0.9412];

            open(app.ComParameters.tcpSocket);
            set(app.ComParameters.tcpSocket, 'ByteOrder', 'little-endian');
            write(app.ComParameters.tcpSocket, app.ComParameters.ConfString, 'uint8'); % Send the configuration to quattrocento
            app.DisconnectQuattrocentoButton.Enable = 'on';
            while k <= nwin - 40
                data = read(app.ComParameters.tcpSocket, [app.ComParameters.nchannel, app.ComParameters.nsamp], 'int16');
                EMG(:,(k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp) = data(app.ComParameters.gridchannels((idxmuscle1-1)*64+1:idxmuscle1*64), :);
                Force(:,(k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp) = (data(app.ComParameters.gridchannels(end)+1,:) - app.RecordParameters.offsetforce);
                Force(:,(k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp) = movmean(Force(:,(k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp),50);
                forcefb(k) = mean(Force((k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp));
                feedback(k) = mean(app.OnlineParameters.targetonline((k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp));
                
                EMGtmp{1} = EMG(app.DecompParameters.EMGmask{idxmuscle1}==0, (k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp);
            
                [signal.Pulsetrain{1}(:,(k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp), Distime{1}((k-1)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp, :), esample2{1}] = getspikesonline(EMGtmp{1}, app.DecompParameters.extensionfactor(idxmuscle1), esample2{1}, MUfilt{1}, app.DecompParameters.norm{idxmuscle1}, noise_centroids{1}, spike_centroids{1}, app.ComParameters.nsamp, str2double(app.FrequencyDropDown.Value));
                
                if k < 40
                    DR(k,:) = sum(Distime{1}((k-1)*app.ComParameters.nsamp+1:8*app.ComParameters.nsamp, :) > 0, 1);
                    plot(app.UIAxes4, Time(1:80), DR(1:80,:), Time(k), DR(k,:),'o', 'LineWidth', 4, 'MarkerSize', 20)
                    app.UIAxes4.XLim = [Time(1) Time(80)];
                    plot(app.UIAxes5, 1, forcefb(k),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
                    line(app.UIAxes5,[0 2], [feedback(k)*0.95 feedback(k)*0.95],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                    line(app.UIAxes5,[0 2], [feedback(k)*1.05 feedback(k)*1.05],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                else
                    DR(k,:) = sum(Distime{1}((k-7)*app.ComParameters.nsamp+1:k*app.ComParameters.nsamp, :) > 0, 1);
                    DR(k,:) = mean(DR(k-3:k,:), 1);
                    plot(app.UIAxes4, Time(k-39:k+40), DR(k-39:k+40,:), Time(k), DR(k,:),'o', 'LineWidth', 4, 'MarkerSize', 20)
                    app.UIAxes4.XLim = [Time(k-39) Time(k+40)];
                    plot(app.UIAxes5, 1, forcefb(k),'o', 'LineWidth', 4, 'MarkerSize', 20, 'Color', [0.9412 0.9412 0.9412])
                    line(app.UIAxes5,[0 2], [feedback(k)*0.95 feedback(k)*0.95],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                    line(app.UIAxes5,[0 2], [feedback(k)*1.05 feedback(k)*1.05],'LineWidth', 4, 'Color', [0.9412 0.9412 0.9412])
                end
                app.UIAxes5.YLim = [0 max(app.OnlineParameters.targetonline)*1.25];
                app.UIAxes4.YLim = [0 max(app.DecompParameters.DR{idxmuscle1})*1.2];

                drawnow
                k = k+1;
            end
            app.ComParameters.ConfString(1) = app.ComParameters.ConfString(1) - 1; % Update the first byte that start the data transer
            app.ComParameters.ConfString(40) = CRC8(app.ComParameters.ConfString, 39);            
            write(app.ComParameters.tcpSocket, app.ComParameters.ConfString, 'uint8');
            close(app.ComParameters.tcpSocket); % Close the communication
            flush(app.ComParameters.tcpSocket)

            app.InitializeQuattrocentoButton_2.BackgroundColor = [0.15 0.15 0.15];

            signal.data = EMG;
            signal.target = app.OnlineParameters.targetonline;
            signal.path = Force;
            signal.fsamp = str2double(app.FrequencyDropDown.Value);
            signal.muscle = app.RecordParameters.Muscles{idxmuscle1};
            signal.nChan = size(EMG,1);
            signal.ngrid = app.RecordParameters.ngrid; 
            signal.EMGmask{1} = app.DecompParameters.EMGmask{idxmuscle1};
            signal.gridname{1} = 'GR08MM1305';
            signal.feedback{1} = 'Smooth_DR';

            for k = 1:size(Distime{1},2)
                signal.Dischargetimes{k} = find(Distime{1}(:,k) > 0);
            end

            save([app.EditField_saving.Value '/Online ' datestr(datetime('now')) '.mat'], 'signal');
        end

        % Button pushed function: InitializeQuattrocentoButton_2
        function InitializeQuattrocentoButton_2Pushed(app, event)
            app.ComParameters.fsamp = str2double(app.FrequencyDropDown.Value);
            app.ComParameters.nsamp = floor(app.ComParameters.fsamp/str2double(app.RefreshrateDropDown.Value));
            app.ComParameters.highpass = str2double(app.HPfilterDropDown.Value);
            app.ComParameters.lowpass = str2double(app.LPfilterDropDown.Value);
            app.ComParameters.ConfString = bitseqquattro(app.ComParameters.fsamp, app.ComParameters.nchannel, app.ComParameters.highpass, app.ComParameters.lowpass);
            
            app.ComParameters.tcpSocket = tcpclient('169.254.1.10', 23456);
            app.ComParameters.tcpSocket.InputBufferSize = 2 * app.ComParameters.nchannel * app.ComParameters.fsamp;
            app.GriddisplayedDropDown.Enable = 'on';
            app.InitializeQuattrocentoButton_2.BackgroundColor = 'Green';
            app.DisconnectQuattrocentoButton_2.BackgroundColor = [0.15 0.15 0.15];
            app.DisconnectQuattrocentoButton_2.Enable = 'on';
            app.StartVisualizationCheckEMGButton.Enable = 'on';
            app.StartVisualizationChecknoiseButton.Enable = 'on';
            app.StartButton.Enable = 'on';
            app.StartMVCButton.Enable = 'on';
        end

        % Button pushed function: DisconnectQuattrocentoButton_2
        function DisconnectQuattrocentoButton_2Pushed(app, event)
            app.ComParameters.ConfString(1) = bin2dec('10000000'); % Update the first byte that start the data transer
            app.ComParameters.ConfString(40) = CRC8(app.ComParameters.ConfString, 39);            
            write(app.ComParameters.tcpSocket, app.ComParameters.ConfString, 'uint8');
            close(app.ComParameters.tcpSocket); % Close the communication
            flush(app.ComParameters.tcpSocket)
            app.InitializeQuattrocentoButton_2.BackgroundColor = [0.15 0.15 0.15];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RecordingTab = uitab(app.TabGroup);
            app.RecordingTab.Title = 'Recording';
            app.RecordingTab.ButtonDownFcn = createCallbackFcn(app, @RecordingTabButtonDown, true);
            app.TrainingTab = uitab(app.TabGroup);
            app.TrainingTab.Title = 'Training';
            app.TrainingTab.ButtonDownFcn = createCallbackFcn(app, @TrainingTabButtonDown, true);
            app.BiofeedbackTab = uitab(app.TabGroup);
            app.BiofeedbackTab.Title = 'Biofeedback';
            app.BiofeedbackTab.ButtonDownFcn = createCallbackFcn(app, @BiofeedbackTabButtonDown, true);
            app.VisualizationBiofeedback = uipanel(app.UIFigure);
            app.VisualizationBiofeedback.Title = 'Biofeedback based on MUs';
            app.UIAxes4 = uiaxes(app.VisualizationBiofeedback);
            xlabel(app.UIAxes4, 'Discharge rate (pps)')
            ylabel(app.UIAxes4, 'Discharge rate (pps)')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.VisualizationBiofeedback);
            ylabel(app.UIAxes5, 'Force (%MVC)')
            app.XaxisdisplayedDropDown = uidropdown(app.VisualizationBiofeedback);
            app.XaxisdisplayedDropDown.Items = {'No MUs'};
            app.XaxisdisplayedDropDown.Enable = 'off';
            app.XaxisdisplayedDropDown.Value = 'No MUs';
            app.XaxisdisplayedDropDownLabel = uilabel(app.VisualizationBiofeedback);
            app.XaxisdisplayedDropDownLabel.Text = 'X axis displayed';
            app.DisconnectQuattrocentoButton_2 = uibutton(app.VisualizationBiofeedback, 'push');
            app.DisconnectQuattrocentoButton_2.ButtonPushedFcn = createCallbackFcn(app, @DisconnectQuattrocentoButton_2Pushed, true);
            app.DisconnectQuattrocentoButton_2.Enable = 'off';
            app.DisconnectQuattrocentoButton_2.Text = 'Disconnect Quattrocento';
            app.YaxisdisplayedDropDown = uidropdown(app.VisualizationBiofeedback);
            app.YaxisdisplayedDropDown.Items = {'No MUs'};
            app.YaxisdisplayedDropDown.Enable = 'off';
            app.YaxisdisplayedDropDown.Value = 'No MUs';
            app.YaxisdisplayedDropDownLabel = uilabel(app.VisualizationBiofeedback);
            app.YaxisdisplayedDropDownLabel.Text = 'Y axis displayed';
            app.InitializeQuattrocentoButton_2 = uibutton(app.VisualizationBiofeedback, 'push');
            app.InitializeQuattrocentoButton_2.ButtonPushedFcn = createCallbackFcn(app, @InitializeQuattrocentoButton_2Pushed, true);
            app.InitializeQuattrocentoButton_2.Text = 'Initialize Quattrocento';
            app.BiofeedbackSettingsPanel = uipanel(app.UIFigure);
            app.BiofeedbackSettingsPanel.Title = 'Biofeedback Settings';
            app.UIAxes2 = uiaxes(app.BiofeedbackSettingsPanel);
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Force')
            zlabel(app.UIAxes2, 'Z')
            app.MVCtargetEditFieldLabel = uilabel(app.BiofeedbackSettingsPanel);
            app.MVCtargetEditFieldLabel.Text = 'MVC target';
            app.MVCtargetEditField = uieditfield(app.BiofeedbackSettingsPanel, 'numeric');
            app.MVCtargetEditField.ValueChangedFcn = createCallbackFcn(app, @MVCtargetEditFieldValueChanged, true);
            app.DurationrampEditFieldLabel = uilabel(app.BiofeedbackSettingsPanel);
            app.DurationrampEditFieldLabel.Text = 'Duration ramp';
            app.DurationrampEditField = uieditfield(app.BiofeedbackSettingsPanel, 'numeric');
            app.DurationrampEditField.ValueChangedFcn = createCallbackFcn(app, @DurationrampEditFieldValueChanged, true);
            app.DurationplateauEditFieldLabel = uilabel(app.BiofeedbackSettingsPanel);
            app.DurationplateauEditFieldLabel.Text = 'Duration plateau';
            app.DurationplateauEditField = uieditfield(app.BiofeedbackSettingsPanel, 'numeric');
            app.DurationplateauEditField.ValueChangedFcn = createCallbackFcn(app, @DurationplateauEditFieldValueChanged, true);
            app.DurationrestEditFieldLabel = uilabel(app.BiofeedbackSettingsPanel);
            app.DurationrestEditFieldLabel.Text = 'Duration rest';
            app.DurationrestEditField = uieditfield(app.BiofeedbackSettingsPanel, 'numeric');
            app.DurationrestEditField.ValueChangedFcn = createCallbackFcn(app, @DurationrestEditFieldValueChanged, true);
            app.NbiterationsEditFieldLabel = uilabel(app.BiofeedbackSettingsPanel);
            app.NbiterationsEditFieldLabel.Text = 'Nb iterations';
            app.NbiterationsEditField = uieditfield(app.BiofeedbackSettingsPanel, 'numeric');
            app.NbiterationsEditField.ValueChangedFcn = createCallbackFcn(app, @NbiterationsEditFieldValueChanged, true);
            app.NbiterationsEditField.Value = 1;
            app.StartQuadrantwithCSTsButton = uibutton(app.BiofeedbackSettingsPanel, 'push');
            app.StartQuadrantwithCSTsButton.ButtonPushedFcn = createCallbackFcn(app, @StartQuadrantwithCSTsButtonPushed, true);
            app.StartQuadrantwithCSTsButton.Text = 'Start Quadrant with CSTs';
            app.StartQuadrantwithMUsButton = uibutton(app.BiofeedbackSettingsPanel, 'push');
            app.StartQuadrantwithMUsButton.ButtonPushedFcn = createCallbackFcn(app, @StartQuadrantwithMUsButtonPushed, true);
            app.StartQuadrantwithMUsButton.Text = 'Start Quadrant with MUs';
            app.StartDRofalltheMUsButton = uibutton(app.BiofeedbackSettingsPanel, 'push');
            app.StartDRofalltheMUsButton.ButtonPushedFcn = createCallbackFcn(app, @StartDRofalltheMUsButtonPushed, true);
            app.StartDRofalltheMUsButton.Text = 'Start DR of all the MUs';
            app.ForceparametersLabel = uilabel(app.BiofeedbackSettingsPanel);
            app.ForceparametersLabel.Text = 'Force parameters';
            app.TypeofbiofeedbacksLabel = uilabel(app.BiofeedbackSettingsPanel);
            app.TypeofbiofeedbacksLabel.Text = 'Type of biofeedbacks';
            app.TrainingSettingsPanel = uipanel(app.UIFigure);
            app.TrainingSettingsPanel.Title = 'Training Settings';
            app.UIAxes = uiaxes(app.TrainingSettingsPanel);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Force')
            zlabel(app.UIAxes, 'Z')
            app.MVCparametersLabel = uilabel(app.TrainingSettingsPanel);
            app.MVCparametersLabel.Text = 'MVC parameters';
            app.DurationEditFieldLabel = uilabel(app.TrainingSettingsPanel);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.TrainingSettingsPanel, 'numeric');
            app.RestEditFieldLabel = uilabel(app.TrainingSettingsPanel);
            app.RestEditFieldLabel.Text = 'Rest';
            app.RestEditField = uieditfield(app.TrainingSettingsPanel, 'numeric');
            app.OffsetCheckBox = uicheckbox(app.TrainingSettingsPanel);
            app.OffsetCheckBox.ValueChangedFcn = createCallbackFcn(app, @OffsetCheckBoxValueChanged, true);
            app.OffsetCheckBox.Text = 'Offset';
            app.EditField_offset = uieditfield(app.TrainingSettingsPanel, 'numeric');
            app.StartMVCButton = uibutton(app.TrainingSettingsPanel, 'push');
            app.StartMVCButton.ButtonPushedFcn = createCallbackFcn(app, @StartMVCButtonPushed, true);
            app.StartMVCButton.Text = 'Start MVC';
            app.Gauge = uigauge(app.TrainingSettingsPanel, 'semicircular');
            app.MVCTargetEditFieldLabel = uilabel(app.TrainingSettingsPanel);
            app.MVCTargetEditFieldLabel.Text = 'MVC Target';
            app.MVCTargetEditField = uieditfield(app.TrainingSettingsPanel, 'numeric');
            app.MVCTargetEditField.ValueChangedFcn = createCallbackFcn(app, @MVCTargetEditFieldValueChanged, true);
            app.DurationtrainingEditFieldLabel = uilabel(app.TrainingSettingsPanel);
            app.DurationtrainingEditFieldLabel.Text = 'Duration training';
            app.DurationtrainingEditField = uieditfield(app.TrainingSettingsPanel, 'numeric');
            app.DurationtrainingEditField.ValueChangedFcn = createCallbackFcn(app, @DurationtrainingEditFieldValueChanged, true);
            app.TrainingparametersLabel = uilabel(app.TrainingSettingsPanel);
            app.TrainingparametersLabel.Text = 'Training parameters';
            app.NbIterationsEditFieldLabel = uilabel(app.TrainingSettingsPanel);
            app.NbIterationsEditFieldLabel.Text = 'Nb Iterations';
            app.NbIterationsEditField = uieditfield(app.TrainingSettingsPanel, 'numeric');
            app.StartButton = uibutton(app.TrainingSettingsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EditField_MVC = uieditfield(app.TrainingSettingsPanel, 'text');
            app.SILthresholdEditFieldLabel = uilabel(app.TrainingSettingsPanel);
            app.SILthresholdEditFieldLabel.Text = 'SIL threshold';
            app.SILthresholdEditField = uieditfield(app.TrainingSettingsPanel, 'numeric');
            app.SILthresholdEditField.Value = 0.9;
            app.COVthresholdEditFieldLabel = uilabel(app.TrainingSettingsPanel);
            app.COVthresholdEditFieldLabel.Text = 'COV threshold';
            app.COVthresholdEditField = uieditfield(app.TrainingSettingsPanel, 'numeric');
            app.COVthresholdEditField.Value = 0.5;
            app.VisualizationPanel = uipanel(app.UIFigure);
            app.VisualizationPanel.Title = 'Visualization';
            app.UIAxes3 = uiaxes(app.VisualizationPanel);
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Channels')
            zlabel(app.UIAxes3, 'Z')
            app.GriddisplayedDropDown = uidropdown(app.VisualizationPanel);
            app.GriddisplayedDropDown.Items = {'No Muscles'};
            app.GriddisplayedDropDown.Enable = 'off';
            app.GriddisplayedDropDown.Value = 'No Muscles';
            app.GriddisplayedDropDownLabel = uilabel(app.VisualizationPanel);
            app.GriddisplayedDropDownLabel.Text = 'Grid displayed #';
            app.DisconnectQuattrocentoButton = uibutton(app.VisualizationPanel, 'push');
            app.DisconnectQuattrocentoButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectQuattrocentoButtonPushed, true);
            app.DisconnectQuattrocentoButton.Enable = 'off';
            app.DisconnectQuattrocentoButton.Text = 'Disconnect Quattrocento';
            app.InitializeQuattrocentoButton = uibutton(app.VisualizationPanel, 'push');
            app.InitializeQuattrocentoButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeQuattrocentoButtonPushed, true);
            app.InitializeQuattrocentoButton.Text = 'Initialize Quattrocento';
            app.RecordingSettingsPanel = uipanel(app.UIFigure);
            app.RecordingSettingsPanel.Title = 'Recording Settings';
            app.Grid1CheckBox = uicheckbox(app.RecordingSettingsPanel);
            app.Grid1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Grid1CheckBoxValueChanged, true);
            app.Grid1CheckBox.Text = 'Grid #1';
            app.Grid2CheckBox = uicheckbox(app.RecordingSettingsPanel);
            app.Grid2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Grid2CheckBoxValueChanged, true);
            app.Grid2CheckBox.Enable = 'off';
            app.Grid2CheckBox.Text = 'Grid #2';
            app.Grid3CheckBox = uicheckbox(app.RecordingSettingsPanel);
            app.Grid3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Grid3CheckBoxValueChanged, true);
            app.Grid3CheckBox.Enable = 'off';
            app.Grid3CheckBox.Text = 'Grid #3';
            app.Grid4CheckBox = uicheckbox(app.RecordingSettingsPanel);
            app.Grid4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Grid4CheckBoxValueChanged, true);
            app.Grid4CheckBox.Enable = 'off';
            app.Grid4CheckBox.Text = 'Grid #4';
            app.EditField_1 = uieditfield(app.RecordingSettingsPanel, 'text');
            app.EditField_1.ValueChangedFcn = createCallbackFcn(app, @EditField_1ValueChanged, true);
            app.EditField_1.Editable = 'off';
            app.EditField_2 = uieditfield(app.RecordingSettingsPanel, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Editable = 'off';
            app.EditField_3 = uieditfield(app.RecordingSettingsPanel, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Editable = 'off';
            app.EditField_4 = uieditfield(app.RecordingSettingsPanel, 'text');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Editable = 'off';
            app.ChannelsLabel = uilabel(app.RecordingSettingsPanel);
            app.ChannelsLabel.Text = 'Channels';
            app.MusclenamesLabel = uilabel(app.RecordingSettingsPanel);
            app.MusclenamesLabel.Text = 'Muscle names';
            app.FrequencyDropDownLabel = uilabel(app.RecordingSettingsPanel);
            app.FrequencyDropDownLabel.Text = 'Frequency';
            app.FrequencyDropDown = uidropdown(app.RecordingSettingsPanel);
            app.FrequencyDropDown.Items = {'512', '2048', '5120', '10240'};
            app.FrequencyDropDown.Value = '10240';
            app.StartVisualizationCheckEMGButton = uibutton(app.RecordingSettingsPanel, 'push');
            app.StartVisualizationCheckEMGButton.ButtonPushedFcn = createCallbackFcn(app, @StartVisualizationCheckEMGButtonPushed, true);
            app.StartVisualizationCheckEMGButton.Enable = 'off';
            app.StartVisualizationCheckEMGButton.Text = 'Start Visualization -  Check EMG';
            app.SavingsettingsLabel = uilabel(app.RecordingSettingsPanel);
            app.SavingsettingsLabel.Text = 'Saving settings';
            app.EditField_saving = uieditfield(app.RecordingSettingsPanel, 'text');
            app.EditField_saving.Editable = 'off';
            app.EditField_saving.Value = 'Folder name';
            app.SelectfolderButton = uibutton(app.RecordingSettingsPanel, 'push');
            app.SelectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderButtonPushed, true);
            app.SelectfolderButton.Text = 'Select folder';
            app.StartVisualizationChecknoiseButton = uibutton(app.RecordingSettingsPanel, 'push');
            app.StartVisualizationChecknoiseButton.ButtonPushedFcn = createCallbackFcn(app, @StartVisualizationChecknoiseButtonPushed, true);
            app.StartVisualizationChecknoiseButton.Enable = 'off';
            app.StartVisualizationChecknoiseButton.Text = 'Start Visualization -  Check noise';
            app.HPfilterDropDownLabel = uilabel(app.RecordingSettingsPanel);
            app.HPfilterDropDownLabel.Text = 'HP filter';
            app.HPfilterDropDown = uidropdown(app.RecordingSettingsPanel);
            app.HPfilterDropDown.Items = {'200', '100', '10', '0.3'};
            app.HPfilterDropDown.Value = '10';
            app.LPfilterDropDownLabel = uilabel(app.RecordingSettingsPanel);
            app.LPfilterDropDownLabel.Text = 'LP filter';
            app.LPfilterDropDown = uidropdown(app.RecordingSettingsPanel);
            app.LPfilterDropDown.Items = {'4400', '900', '500', '130'};
            app.LPfilterDropDown.Value = '500';
            app.RefreshrateDropDownLabel = uilabel(app.RecordingSettingsPanel);
            app.RefreshrateDropDownLabel.Text = 'Refresh rate';
            app.RefreshrateDropDown = uidropdown(app.RecordingSettingsPanel);
            app.RefreshrateDropDown.Items = {'1', '2', '4', '8'};
            app.RefreshrateDropDown.Value = '8';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HDsEMG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end